classdef ENGR_DB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label2                          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        READYLabel                      matlab.ui.control.Label
        Image16                         matlab.ui.control.Image
        Image15                         matlab.ui.control.Image
        Version100Label                 matlab.ui.control.Label
        Image14                         matlab.ui.control.Image
        Date20082026Label               matlab.ui.control.Label
        ABDULFATAHMUSTAPHAOLABISILabel  matlab.ui.control.Label
        AuthorLabel                     matlab.ui.control.Label
        EngineeringSurveyingDesignToolLabel  matlab.ui.control.Label
        RAILWAYDESIGNLEVELCALCULATORLabel  matlab.ui.control.Label
        Image12                         matlab.ui.control.Image
        Image6                          matlab.ui.control.Image
        RESULTSPanel                    matlab.ui.container.Panel
        Image13                         matlab.ui.control.Image
        ResultsTable                    matlab.ui.control.Table
        STATUSLOGSPanel                 matlab.ui.container.Panel
        Image5                          matlab.ui.control.Image
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextArea_2Label           matlab.ui.control.Label
        SAVERESULTPanel                 matlab.ui.container.Panel
        SaveResultButton                matlab.ui.control.Button
        InputCSVFilePanel               matlab.ui.container.Panel
        Image11_2                       matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
        Image7                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        CSVStatusLabel                  matlab.ui.control.Label
        LoadCSVButton                   matlab.ui.control.Button
        CSVFileLabel                    matlab.ui.control.Label
        DESIGNOPTIONSPanel              matlab.ui.container.Panel
        CLEARButton_2                   matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        CLEARButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        ThicknessDropDown               matlab.ui.control.DropDown
        SelectThicknessDropDownLabel_2  matlab.ui.control.Label
        DesignLayerDropDown             matlab.ui.control.DropDown
        SelectlayersLabel_2             matlab.ui.control.Label
        ThicknessLabel                  matlab.ui.control.Label
        DesignLayerLabel                matlab.ui.control.Label
        EngineeringDatabasePanel        matlab.ui.container.Panel
        SectionDropDown                 matlab.ui.control.DropDown
        SelectSectionDropDownLabel      matlab.ui.control.Label
        SECTIONSLabel                   matlab.ui.control.Label
        Image11                         matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        DatabaseStatusLabel             matlab.ui.control.Label
        LoadDatabaseButton              matlab.ui.control.Button
        DatabaseFileLabel               matlab.ui.control.Label
    end


    properties (Access = private)
        EngineeringDB % Description
        InputTable % Description
    end

    methods (Access = private)

        function addStatus(app,message)

            currentText = app.StatusTextArea.Value;

            if ischar(currentText)
                currentText = {currentText};
            end

            newMessage = sprintf('%s  %s', ...
                char(datetime('now','format','HH:MM:SS')),message);

            app.StatusTextArea.Value = ...
                [currentText; {newMessage}];

            drawnow;


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LoadDatabaseButton
        function LoadDatabaseButtonPushed(app, event)
            section = app.SectionDropDown.Value;

            if strcmp(section,'Section 02')
                expectedFile = 'Section_02_DB.mat';

            elseif strcmp(section,'Section 03')
                expectedFile = 'Section_03_DB.mat';

            else
                uialert(app.UIFigure,...
                    'Please select a section first.',...
                    'Section Missing');
                return;
            end


            % Resolve database path
            if isdeployed

                databaseFile = which(expectedFile);

            else

                appFolder = fileparts(mfilename('fullpath'));
                databaseFile = fullfile(appFolder, expectedFile);

            end


            % Check database file exists
            if isempty(databaseFile) || ~isfile(databaseFile)

                uialert(app.UIFigure,...
                    [expectedFile ' was not found.'],...
                    'File Missing');

                app.DatabaseStatusLabel.Text = 'STATUS: NOT LOADED';
                return;
            end


            % Load database
            data = load(databaseFile,'EngineeringDB');


            % Check EngineeringDB variable exists
            if ~isfield(data,'EngineeringDB')

                uialert(app.UIFigure,...
                    [expectedFile ' does not contain the EngineeringDB table.'],...
                    'Invalid Database');

                return;
            end


            % Store database in app
            app.EngineeringDB = data.EngineeringDB;

            app.DatabaseFileLabel.Text = expectedFile;
            app.DatabaseStatusLabel.Text = 'STATUS: LOADED';

            app.addStatus(...
                [section ' engineering database loaded successfully.']);
        end

        % Button pushed function: LoadCSVButton
        function LoadCSVButtonPushed(app, event)
            % Select input CSV
            [fileName, pathName] = uigetfile( ...
                '*.csv', ...
                'Select Input CSV File');

            % User cancelled
            if isequal(fileName,0)
                app.CSVStatusLabel.Text = 'STATUS: NOT LOADED';
                return;
            end

            % Full file path
            inputFile = fullfile(pathName,fileName);

            % Read CSV
            try
                inputTable = readtable(inputFile);
            catch ME
                uialert(app.UIFigure, ...
                    ['Unable to read CSV file.' newline newline ME.message], ...
                    'CSV Error');
                return;
            end

            % Required columns
            requiredColumns = {'PK','OFFSET','LEVEL'};

            for k = 1:length(requiredColumns)

                if ~ismember(requiredColumns{k}, ...
                        inputTable.Properties.VariableNames)

                    uialert(app.UIFigure, ...
                        ['Missing required column: ' requiredColumns{k}], ...
                        'Invalid CSV');

                    return;
                end

            end

            % Store CSV inside the App
            app.InputTable = inputTable;

            % Update interface
            app.CSVFileLabel.Text = fileName;
            app.CSVStatusLabel.Text = 'STATUS: LOADED';

            % Display records in the App table
            app.ResultsTable.Data = inputTable;
            inputTable.Properties.VariableNames;
            app.addStatus(['CSV loaded: ' fileName]);
        end

        % Value changed function: ThicknessDropDown
        function ThicknessDropDownValueChanged(app, event)

        end

        % Drop down opening function: ThicknessDropDown
        function ThicknessDropDownOpening(app, event)

        end

        % Value changed function: DesignLayerDropDown
        function DesignLayerDropDownValueChanged(app, event)
            layer = app.DesignLayerDropDown.Value;
            if strcmp(layer,'Subgrade')

                app.ThicknessDropDown.Items = {'0.50 m','0.53 m'};
                app.ThicknessDropDown.Value = '0.50 m';

            elseif strcmp(layer,'Sub-ballast')

                app.ThicknessDropDown.Items = {'0.75 m','0.78 m'};
                app.ThicknessDropDown.Value = '0.75 m';
            end
        end

        % Button pushed function: CLEARButton, CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(app.EngineeringDB)
                uialert(app.UIFigure, ...
                    'Please load the Engineering Database first.', ...
                    'Database Missing');
                return;
            end

            if isempty(app.InputTable)
                uialert(app.UIFigure, ...
                    'Please load the Input CSV file first.', ...
                    'CSV Missing');
                return;
            end

            % Get selected thickness
            thickness = sscanf(app.ThicknessDropDown.Value,'%f');

            inputTable = app.InputTable;
            n = height(inputTable);

            % Create result columns
            inputTable.DesignLevel = NaN(n,1);
            inputTable.Difference = NaN(n,1);

            %% Convert Engineering Database chainages once

            DBchainage = string(app.EngineeringDB.Chainage);

            DBclean = erase(DBchainage, ...
                ["CH=","Pk=","PK=","ch="]);

            DBmetres = NaN(height(app.EngineeringDB),1);

            for k = 1:height(app.EngineeringDB)

                dbParts = split(DBclean(k), "+");

                if numel(dbParts) == 2

                    DBmetres(k) = ...
                        str2double(dbParts(1))*1000 + ...
                        str2double(dbParts(2));

                end
            end


            %% Calculate each survey point

            for i = 1:n

                % Get chainage
                if iscell(inputTable.PK)
                    PK = string(inputTable.PK{i});
                else
                    PK = string(inputTable.PK(i));
                end

                % GET OFFSET AND SURVEYED LEVEL
                Offset = inputTable.OFFSET(i);
                SurveyLevel = inputTable.LEVEL(i);

                % Clean input chainage
                PKclean = erase(PK, ...
                    ["CH=","Pk=","PK=","ch="]);

                % Convert chainage to metres
                parts = split(PKclean, "+");

                if numel(parts) ~= 2

                    uialert(app.UIFigure, ...
                        ['Invalid chainage format: ' char(PK)], ...
                        'Chainage Error');

                    return;
                end

                PKmetres = ...
                    str2double(parts(1))*1000 + ...
                    str2double(parts(2));

                % Find chainage in Engineering Database
                idx = abs(DBmetres - PKmetres) < 0.001;

                if ~any(idx)

                    uialert(app.UIFigure, ...
                        ['Chainage not found: ' char(PK)], ...
                        'Chainage Error');

                    return;
                end

                % Get base level
                BaseLevel = app.EngineeringDB.BaseLevel(idx);

                % Select crossfall according to side
                if Offset < 0

                    Crossfall = ...
                        app.EngineeringDB.LeftCrossfall(idx)/100;

                else

                    Crossfall = ...
                        app.EngineeringDB.RightCrossfall(idx)/100;

                end

                % Get crossfall direction
                CrossfallSign = ...
                    app.EngineeringDB.CrossfallSign(idx);


                %% Calculate Design Level

                if CrossfallSign == 1

                    % Right side is high

                    if Offset < 0

                        DesignLevel = ...
                            BaseLevel + thickness ...
                            - Crossfall*abs(Offset);

                    else

                        DesignLevel = ...
                            BaseLevel + thickness ...
                            + Crossfall*abs(Offset);

                    end

                else

                    % Left side is high

                    if Offset < 0

                        DesignLevel = ...
                            BaseLevel + thickness ...
                            + Crossfall*abs(Offset);

                    else

                        DesignLevel = ...
                            BaseLevel + thickness ...
                            - Crossfall*abs(Offset);

                    end

                end


                %% Store results

                DesignLevel = round(DesignLevel,3);

                Difference = ...
                    round(SurveyLevel - DesignLevel,4);

                inputTable.DesignLevel(i) = DesignLevel;
                inputTable.Difference(i) = Difference;

            end


            %% Display Results

            app.InputTable = inputTable;

            displayTable = inputTable;

            % Display difference to 4 decimal places
            displayTable.Difference = ...
                compose('%.4f',inputTable.Difference);

            app.ResultsTable.Data = displayTable;


            %% Color Difference values

            removeStyle(app.ResultsTable);

            greenStyle = uistyle( ...
                'FontColor',[0.20 0.85 0.35], ...
                'FontWeight','bold');

            redStyle = uistyle( ...
                'FontColor',[0.95 0.20 0.20], ...
                'FontWeight','bold');

            % Find Difference column automatically
            differenceColumn = ...
                find(strcmp(displayTable.Properties.VariableNames, ...
                'Difference'));

            for i = 1:n

                if app.InputTable.Difference(i) == 0

                    addStyle(app.ResultsTable, ...
                        greenStyle, ...
                        'cell',[i differenceColumn]);

                else

                    addStyle(app.ResultsTable, ...
                        redStyle, ...
                        'cell',[i differenceColumn]);

                end

            end


            %% Status

            app.CSVStatusLabel.Text = ...
                'STATUS: CALCULATION COMPLETE';

            uialert(app.UIFigure, ...
                sprintf('%d records calculated successfully.',n), ...
                'Calculation Complete');

            app.addStatus( ...
                sprintf('Calculation completed: %d records.',n));
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            if isempty(app.InputTable) || ~ismember('DesignLevel',app.InputTable.Properties.VariableNames)
                uialert(app.UIFigure,'Please calculate the results first.','No Results');
                return;
            end

            [fileName,pathName] = uiputfile('*.csv',...
                'Save Calculated Results As',...
                'calculated_design.csv');

            if isequal(fileName,0)
                return;
            end

            outputFile = fullfile(pathName,fileName);

            writetable(app.InputTable,outputFile);

            app.CSVStatusLabel.Text = 'STATUS: RESULTS SAVED';

            uialert(app.UIFigure,...
                ['Results saved successfully:' newline outputFile],...
                'Save Complete');
            app.addStatus(['Resukts saved: ' fileName]);
        end

        % Button pushed function: CLEARButton_2
        function CLEARButton_2Pushed(app, event)
            % Clear input data stored in the app
            app.InputTable = table();

            % Clear Results table
            app.ResultsTable.Data = table();

            % Reset CSV status
            app.CSVStatusLabel.Text = 'STATUS: WAITING';

            % Reset database status
            app.DatabaseStatusLabel.Text = 'STATUS: NOT LOADED';

            % Reset selected CSV file display
            app.CSVFileLabel.Text = 'No CSV file selected';

            % Reset database file display
            app.DatabaseFileLabel.Text = 'No database selected';

            % Clear status log
            app.StatusTextArea.Value = {'Ready.'};

            % Remove any table cell colors/styles
            removeStyle(app.ResultsTable);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EngineeringDatabasePanel = uipanel(app.UIFigure);
            app.EngineeringDatabasePanel.Title = 'ENGINEERING DATABASE';
            app.DatabaseFileLabel = uilabel(app.EngineeringDatabasePanel);
            app.DatabaseFileLabel.Text = 'EngineeringDB.mat';
            app.LoadDatabaseButton = uibutton(app.EngineeringDatabasePanel, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseButtonPushed, true);
            app.LoadDatabaseButton.Text = 'LOAD DATABASE ';
            app.DatabaseStatusLabel = uilabel(app.EngineeringDatabasePanel);
            app.DatabaseStatusLabel.Text = 'STATUS: NOT LOADED';
            app.Image4 = uiimage(app.EngineeringDatabasePanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'database.png');
            app.Image8 = uiimage(app.EngineeringDatabasePanel);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'folder.png');
            app.Image9 = uiimage(app.EngineeringDatabasePanel);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'check.png');
            app.Image11 = uiimage(app.EngineeringDatabasePanel);
            app.Image11.ImageSource = fullfile(pathToMLAPP, 'file.png');
            app.SECTIONSLabel = uilabel(app.EngineeringDatabasePanel);
            app.SECTIONSLabel.Text = ' SECTIONS';
            app.SelectSectionDropDownLabel = uilabel(app.EngineeringDatabasePanel);
            app.SelectSectionDropDownLabel.Text = 'Select Section';
            app.SectionDropDown = uidropdown(app.EngineeringDatabasePanel);
            app.SectionDropDown.Items = {'Section 02', 'Section 03'};
            app.SectionDropDown.Value = 'Section 02';
            app.DESIGNOPTIONSPanel = uipanel(app.UIFigure);
            app.DESIGNOPTIONSPanel.Title = '    DESIGN OPTIONS';
            app.DesignLayerLabel = uilabel(app.DESIGNOPTIONSPanel);
            app.DesignLayerLabel.Text = 'DESIGN LAYER';
            app.ThicknessLabel = uilabel(app.DESIGNOPTIONSPanel);
            app.ThicknessLabel.Text = 'THICKNESS';
            app.SelectlayersLabel_2 = uilabel(app.DESIGNOPTIONSPanel);
            app.SelectlayersLabel_2.Text = 'Select layers';
            app.DesignLayerDropDown = uidropdown(app.DESIGNOPTIONSPanel);
            app.DesignLayerDropDown.Items = {'Subgrade', 'Sub-ballast'};
            app.DesignLayerDropDown.ValueChangedFcn = createCallbackFcn(app, @DesignLayerDropDownValueChanged, true);
            app.DesignLayerDropDown.Value = 'Subgrade';
            app.SelectThicknessDropDownLabel_2 = uilabel(app.DESIGNOPTIONSPanel);
            app.SelectThicknessDropDownLabel_2.Text = 'Select Thickness';
            app.ThicknessDropDown = uidropdown(app.DESIGNOPTIONSPanel);
            app.ThicknessDropDown.Items = {'0.50m', '0.53m', '0.75m', '0.78m'};
            app.ThicknessDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ThicknessDropDownOpening, true);
            app.ThicknessDropDown.ValueChangedFcn = createCallbackFcn(app, @ThicknessDropDownValueChanged, true);
            app.ThicknessDropDown.Value = '0.50m';
            app.CalculateButton = uibutton(app.DESIGNOPTIONSPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Icon = fullfile(pathToMLAPP, 'calculate.png');
            app.CalculateButton.Text = 'CALCULATE DESIGN LEVELS';
            app.CLEARButton = uibutton(app.DESIGNOPTIONSPanel, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CLEARButton.Icon = fullfile(pathToMLAPP, 'clear.png');
            app.CLEARButton.Text = 'CLEAR';
            app.Image2 = uiimage(app.DESIGNOPTIONSPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'settings.png');
            app.CLEARButton_2 = uibutton(app.DESIGNOPTIONSPanel, 'push');
            app.CLEARButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLEARButton_2Pushed, true);
            app.CLEARButton_2.Icon = fullfile(pathToMLAPP, 'clear.png');
            app.CLEARButton_2.Text = 'CLEAR';
            app.InputCSVFilePanel = uipanel(app.UIFigure);
            app.InputCSVFilePanel.Title = '    INPUT CSV FILE';
            app.CSVFileLabel = uilabel(app.InputCSVFilePanel);
            app.CSVFileLabel.Text = 'No file Selected ';
            app.LoadCSVButton = uibutton(app.InputCSVFilePanel, 'push');
            app.LoadCSVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVButtonPushed, true);
            app.LoadCSVButton.Text = 'SELECT CSV FILE ';
            app.CSVStatusLabel = uilabel(app.InputCSVFilePanel);
            app.CSVStatusLabel.Text = 'STATUS: WAITING';
            app.Image3 = uiimage(app.InputCSVFilePanel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'csv_file.png');
            app.Image7 = uiimage(app.InputCSVFilePanel);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'folder.png');
            app.Image10 = uiimage(app.InputCSVFilePanel);
            app.Image10.ImageSource = fullfile(pathToMLAPP, 'clock.png');
            app.Image11_2 = uiimage(app.InputCSVFilePanel);
            app.Image11_2.ImageSource = fullfile(pathToMLAPP, 'file.png');
            app.SAVERESULTPanel = uipanel(app.UIFigure);
            app.SAVERESULTPanel.Title = 'SAVE RESULT';
            app.SaveResultButton = uibutton(app.SAVERESULTPanel, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveResultButton.Text = 'SAVE RESULT';
            app.STATUSLOGSPanel = uipanel(app.UIFigure);
            app.STATUSLOGSPanel.Title = '   STATUS/LOGS';
            app.StatusTextArea_2Label = uilabel(app.STATUSLOGSPanel);
            app.StatusTextArea_2Label.Text = 'Status';
            app.StatusTextArea = uitextarea(app.STATUSLOGSPanel);
            app.Image5 = uiimage(app.STATUSLOGSPanel);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'logs.png');
            app.RESULTSPanel = uipanel(app.UIFigure);
            app.RESULTSPanel.Title = '    RESULTS';
            app.ResultsTable = uitable(app.RESULTSPanel);
            app.ResultsTable.ColumnName = {'PK'; '                             OFFSET'; 'LEVEL'; 'DESIGNLEVEL'; 'DIFFERENCE'};
            app.ResultsTable.RowName = {};
            app.Image13 = uiimage(app.RESULTSPanel);
            app.Image13.ImageSource = fullfile(pathToMLAPP, 'user.png');
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'table.png');
            app.Image12 = uiimage(app.UIFigure);
            app.Image12.ImageSource = fullfile(pathToMLAPP, 'app_logo.png');
            app.RAILWAYDESIGNLEVELCALCULATORLabel = uilabel(app.UIFigure);
            app.RAILWAYDESIGNLEVELCALCULATORLabel.Text = 'RAILWAY DESIGN LEVEL CALCULATOR';
            app.EngineeringSurveyingDesignToolLabel = uilabel(app.UIFigure);
            app.EngineeringSurveyingDesignToolLabel.Text = 'Engineering Surveying & Design Tool';
            app.AuthorLabel = uilabel(app.UIFigure);
            app.AuthorLabel.Text = 'Author:';
            app.ABDULFATAHMUSTAPHAOLABISILabel = uilabel(app.UIFigure);
            app.ABDULFATAHMUSTAPHAOLABISILabel.Text = 'ABDULFATAH MUSTAPHA OLABISI';
            app.Date20082026Label = uilabel(app.UIFigure);
            app.Date20082026Label.Text = 'Date: 20/08/2026';
            app.Image14 = uiimage(app.UIFigure);
            app.Image14.ImageSource = fullfile(pathToMLAPP, 'calendar.png');
            app.Version100Label = uilabel(app.UIFigure);
            app.Version100Label.Text = 'Version: 1.0.0';
            app.Image15 = uiimage(app.UIFigure);
            app.Image15.ImageSource = fullfile(pathToMLAPP, 'info.png');
            app.Image16 = uiimage(app.UIFigure);
            app.Image16.ImageSource = fullfile(pathToMLAPP, 'status_ready.png');
            app.READYLabel = uilabel(app.UIFigure);
            app.READYLabel.Text = 'READY';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENGR_DB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end